classdef MicrogliaCaAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ValleyHeightThreshEditFieldLabel  matlab.ui.control.Label
        ValleyHeightThreshEditField   matlab.ui.control.NumericEditField
        PeakPromThreshEditFieldLabel  matlab.ui.control.Label
        PeakPromThreshEditField       matlab.ui.control.NumericEditField
        LoaddatatoprocessButton       matlab.ui.control.Button
        MainProgramSettingsLabel      matlab.ui.control.Label
        PeakDetectionSettingsLabel    matlab.ui.control.Label
        SignalFilterSettingsLabel     matlab.ui.control.Label
        LowpassFilterCutoffEditFieldLabel  matlab.ui.control.Label
        LowpassFilterCutoffEditField  matlab.ui.control.NumericEditField
        FilterOrderCoeffsEditFieldLabel  matlab.ui.control.Label
        FilterOrderCoeffsEditField    matlab.ui.control.NumericEditField
        DataSamplingRateFsEditFieldLabel  matlab.ui.control.Label
        DataSamplingRateFsEditField   matlab.ui.control.NumericEditField
        DetrendingPolynomialDegreeEditFieldLabel  matlab.ui.control.Label
        DetrendingPolynomialDegreeEditField  matlab.ui.control.NumericEditField
        DetrendingBreakpointSeparationEditFieldLabel  matlab.ui.control.Label
        DetrendingBreakpointSeparationEditField  matlab.ui.control.NumericEditField
        RunAnalysisButton             matlab.ui.control.Button
        SaveOutputButton              matlab.ui.control.Button
        ResumeSessionEditFieldLabel   matlab.ui.control.Label
        ResumeSessionEditField        matlab.ui.control.NumericEditField
        processsomapsEditFieldLabel   matlab.ui.control.Label
        processsomapsEditField        matlab.ui.control.EditField
        SortProcessedDataButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        preprocessed % allocate space for imported data
        saveDir      % init. save directory for output data
        resumeSession
        processOrSoma
        % inputs to MainAnalysis
        valleySD
        peakSD
        RESUME_DATA
        % filter inputs
        filterOrder
        Fc
        Fs
        polyDeg
        bp
        % output vars
        EVENT_SUMMARY
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.resumeSession = 0;
            app.processOrSoma = "p";
            app.valleySD = 0.07;
            app.peakSD = 1.8;
            app.filterOrder = 100;
            app.Fc = 0.06;
            app.Fs = 1.04;
            app.polyDeg = 2;
            app.bp = 400;
        end

        % Button pushed function: LoaddatatoprocessButton
        function LoaddatatoprocessButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.mat', 'Select data to process');
            if isequal(fileName,0)
                disp('User selected Cancel')
            else
                app.preprocessed = load([pathName fileName]);
            end
        end

        % Callback function
        function SelectsavedirectoryButtonPushed(app, event)
            app.saveDir = uigetdir();
        end

        % Value changed function: ValleyHeightThreshEditField
        function ValleyHeightThreshEditFieldValueChanged(app, event)
            app.valleySD = app.ValleyHeightThreshEditField.Value;
            
        end

        % Value changed function: PeakPromThreshEditField
        function PeakPromThreshEditFieldValueChanged(app, event)
            app.peakSD = app.PeakPromThreshEditField.Value;
            
        end

        % Value changed function: FilterOrderCoeffsEditField
        function FilterOrderCoeffsEditFieldValueChanged(app, event)
            app.filterOrder = app.FilterOrderCoeffsEditField.Value;
            
        end

        % Value changed function: LowpassFilterCutoffEditField
        function LowpassFilterCutoffEditFieldValueChanged(app, event)
            app.Fc = app.LowpassFilterCutoffEditField.Value;
            
        end

        % Value changed function: DataSamplingRateFsEditField
        function DataSamplingRateFsEditFieldValueChanged(app, event)
            app.Fs  = app.DataSamplingRateFsEditField.Value;
            
        end

        % Value changed function: 
        % DetrendingPolynomialDegreeEditField
        function DetrendingPolynomialDegreeEditFieldValueChanged(app, event)
            app.polyDeg = app.DetrendingPolynomialDegreeEditField.Value;
            
        end

        % Value changed function: 
        % DetrendingBreakpointSeparationEditField
        function DetrendingBreakpointSeparationEditFieldValueChanged(app, event)
            app.bp = app.DetrendingBreakpointSeparationEditField.Value;
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            switch app.processOrSoma
                case "p"
                    if app.resumeSession == 0
                        app.EVENT_SUMMARY = MainAnalysis(app.preprocessed.process_data, app.valleySD,app.peakSD,app.filterOrder,app.Fc,app.Fs,app.polyDeg,app.bp);
                    elseif app.resumeSession == 1
                        app.RESUME_DATA = load(fullfile(uigetfile('*.mat', 'Select data file to resume analysis.')));
                        test = cell2struct(struct2cell(app.RESUME_DATA), {'ProcessedEvents'});
                        ProcessedEvents = test.ProcessedEvents;
                        ResumedEvents = MainAnalysis(app.preprocessed.process_data, app.valleySD,app.peakSD,app.filterOrder,app.Fc,app.Fs,app.polyDeg,app.bp,ProcessedEvents);
                        app.EVENT_SUMMARY = catstruct(ProcessedEvents, ResumedEvents);
                    else
                        error('Encountered error parsing resume session argument.')
                    end
                case "s"
                    value = app.resumeSession.Value;
                    if value == 0
                        app.EVENT_SUMMARY = MainAnalysis(app.preprocessed.soma_data, app.valleySD,app.peakSD,app.filterOrder,app.Fc,app.Fs,app.polyDeg,app.bp);
                    elseif value == 1
                        app.RESUME_DATA = load(fullfile(uigetfile('*.mat', 'Select data file to resume analysis.')));
                        test = cell2struct(struct2cell(app.RESUME_DATA), {'ProcessedEvents'});
                        ProcessedEvents = test.ProcessedEvents;
                        ResumedEvents = MainAnalysis(app.preprocessed.soma_data, app.valleySD,app.peakSD,app.filterOrder,app.Fc,app.Fs,app.polyDeg,app.bp,ProcessedEvents);
                        app.EVENT_SUMMARY = catstruct(ProcessedEvents, ResumedEvents);
                    else
                        error('Encountered error parsing resume session argument.')
                    end
            end
        end

        % Callback function
        function ResumesessionDropDownValueChanged(app, event)

            
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            name = 'ProcessedEvents.mat';
            ProcessedEvents = app.EVENT_SUMMARY;
            [fnm,pth] = uiputfile(name);
            if ischar(fnm)
                save(fullfile(pth,fnm),'ProcessedEvents')
            end
        end

        % Value changed function: ResumeSessionEditField
        function ResumeSessionEditFieldValueChanged(app, event)
            app.resumeSession = app.ResumeSessionEditField.Value;
            
        end

        % Value changed function: processsomapsEditField
        function processsomapsEditFieldValueChanged(app, event)
            app.processOrSoma = app.processsomapsEditField.Value;
            
        end

        % Button pushed function: SortProcessedDataButton
        function SortProcessedDataButtonPushed(app, event)
            unsortedData = load(fullfile(uigetfile('*.mat', 'Select unsorted, processed data to sort.')));
            unsortedData = cell2struct(struct2cell(unsortedData), {'ProcessedEvents'});
            sortedData = SortData(unsortedData.ProcessedEvents);
            uisave('sortedData','SortedTraces');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValleyHeightThreshEditFieldLabel = uilabel(app.UIFigure);
            app.ValleyHeightThreshEditFieldLabel.Text = {'Valley Height Thresh'; ''};
            app.ValleyHeightThreshEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValleyHeightThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ValleyHeightThreshEditFieldValueChanged, true);
            app.ValleyHeightThreshEditField.Value = 0.07;
            app.PeakPromThreshEditFieldLabel = uilabel(app.UIFigure);
            app.PeakPromThreshEditFieldLabel.Text = {'Peak Prom Thresh'; ''};
            app.PeakPromThreshEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakPromThreshEditField.ValueChangedFcn = createCallbackFcn(app, @PeakPromThreshEditFieldValueChanged, true);
            app.PeakPromThreshEditField.Value = 1.8;
            app.LoaddatatoprocessButton = uibutton(app.UIFigure, 'push');
            app.LoaddatatoprocessButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatatoprocessButtonPushed, true);
            app.LoaddatatoprocessButton.Text = 'Load data to process';
            app.MainProgramSettingsLabel = uilabel(app.UIFigure);
            app.MainProgramSettingsLabel.Text = 'Main Program Settings';
            app.PeakDetectionSettingsLabel = uilabel(app.UIFigure);
            app.PeakDetectionSettingsLabel.Text = 'Peak Detection Settings';
            app.SignalFilterSettingsLabel = uilabel(app.UIFigure);
            app.SignalFilterSettingsLabel.Text = 'Signal Filter Settings';
            app.LowpassFilterCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassFilterCutoffEditFieldLabel.Text = 'Lowpass Filter Cutoff';
            app.LowpassFilterCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassFilterCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassFilterCutoffEditFieldValueChanged, true);
            app.LowpassFilterCutoffEditField.Value = 0.06;
            app.FilterOrderCoeffsEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderCoeffsEditFieldLabel.Text = 'Filter Order (# Coeffs)';
            app.FilterOrderCoeffsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterOrderCoeffsEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderCoeffsEditFieldValueChanged, true);
            app.FilterOrderCoeffsEditField.Value = 100;
            app.DataSamplingRateFsEditFieldLabel = uilabel(app.UIFigure);
            app.DataSamplingRateFsEditFieldLabel.Text = 'Data Sampling Rate (Fs)';
            app.DataSamplingRateFsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataSamplingRateFsEditField.ValueChangedFcn = createCallbackFcn(app, @DataSamplingRateFsEditFieldValueChanged, true);
            app.DataSamplingRateFsEditField.Value = 1.04;
            app.DetrendingPolynomialDegreeEditFieldLabel = uilabel(app.UIFigure);
            app.DetrendingPolynomialDegreeEditFieldLabel.Text = 'Detrending Polynomial Degree';
            app.DetrendingPolynomialDegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetrendingPolynomialDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @DetrendingPolynomialDegreeEditFieldValueChanged, true);
            app.DetrendingPolynomialDegreeEditField.Value = 2;
            app.DetrendingBreakpointSeparationEditFieldLabel = uilabel(app.UIFigure);
            app.DetrendingBreakpointSeparationEditFieldLabel.Text = 'Detrending Breakpoint Separation';
            app.DetrendingBreakpointSeparationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetrendingBreakpointSeparationEditField.ValueChangedFcn = createCallbackFcn(app, @DetrendingBreakpointSeparationEditFieldValueChanged, true);
            app.DetrendingBreakpointSeparationEditField.Value = 400;
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis!';
            app.SaveOutputButton = uibutton(app.UIFigure, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'Save Output';
            app.ResumeSessionEditFieldLabel = uilabel(app.UIFigure);
            app.ResumeSessionEditFieldLabel.Text = 'Resume Session';
            app.ResumeSessionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResumeSessionEditField.Limits = [0 1];
            app.ResumeSessionEditField.RoundFractionalValues = 'on';
            app.ResumeSessionEditField.ValueChangedFcn = createCallbackFcn(app, @ResumeSessionEditFieldValueChanged, true);
            app.processsomapsEditFieldLabel = uilabel(app.UIFigure);
            app.processsomapsEditFieldLabel.Text = 'process/soma (p/s)';
            app.processsomapsEditField = uieditfield(app.UIFigure, 'text');
            app.processsomapsEditField.ValueChangedFcn = createCallbackFcn(app, @processsomapsEditFieldValueChanged, true);
            app.processsomapsEditField.Value = 'p';
            app.SortProcessedDataButton = uibutton(app.UIFigure, 'push');
            app.SortProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SortProcessedDataButtonPushed, true);
            app.SortProcessedDataButton.Text = 'Sort Processed Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicrogliaCaAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end